classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        serversMenu                    matlab.ui.container.Menu
        startdataserverMenu            matlab.ui.container.Menu
        startdownloadserverMenu        matlab.ui.container.Menu
        UIAxes                         matlab.ui.control.UIAxes
        solveButton                    matlab.ui.control.Button
        balloontypeListBoxLabel        matlab.ui.control.Label
        balloontypeListBox             matlab.ui.control.ListBox
        stateLabel                     matlab.ui.control.Label
        stateLamp                      matlab.ui.control.Lamp
        burstheightLabel               matlab.ui.control.Label
        undefindedLabel                matlab.ui.control.Label
        launchlatitudeEditFieldLabel   matlab.ui.control.Label
        launchlatitudeEditField        matlab.ui.control.NumericEditField
        launchlongitudeEditFieldLabel  matlab.ui.control.Label
        launchlongitudeEditField       matlab.ui.control.NumericEditField
        latEditFieldLabel              matlab.ui.control.Label
        latEditField                   matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        function results = func(app)
            
        end
        
        function results = func2(app)
            
        end
        
    end
    

    methods (Access = private)

        % Button pushed function: solveButton
        function solveButtonPushed(app, event)
            
            app.solveButton.Enable = 'off';
            mbs=[200,300,350,450,500,600,700,800,1000,1200,1500,2000,3000,800,1000,1200,2000,3000]/1000;
            mps=[250,250,250,250,250,250,250,250,250,1050,1050,1050,1050,250,250,1050,1050,1050]/1000;
            vol0s=[.83 .97 1.03 1.16 1.22 1.5 1.63 .76 2.01 2.99 3.33 3.89 4.97 1.76 2.01 2.99 3.89 4.97];
            dbs=[300 378 412 472 499 605 653 700 786 863 944 1054 1300 738 828 910 1079 1331]/100;
            
            i = find(contains( app.balloontypeListBox.Items,  app.balloontypeListBox.Value))
            
            app.stateLamp.Color = [0 1 0];
            
            app.stateLabel.Text = 'solving';
            Pold =100e3;
            Told =100;
            spheroid = referenceEllipsoid('GRS 80');
            hnext=0;
            
            mbalon=mbs(i);
            mpay=mps(i);
            Vol0=vol0s(i);
            Vold=Vol0;
            DBurst=dbs(i);
            Vburst=pi*DBurst^3/6;
            rogas=0.164;
            mgas=rogas*Vol0;
            
            Mtot=mgas+mbalon+mpay;
            Mgros=mbalon+mpay;
            
            
            mvol=(Vburst-Vol0)/(38300);
            n=16000;
            kt=0;
            endtime=16000;
            t=linspace(0,endtime,n);
            %% start point in lat0 long0 h0 refrence of NED sys
            
            lat=  app.launchlatitudeEditField.Value;
            lon=  app.launchlongitudeEditField.Value;
            
            kt=0;
            x0=0;
            y0=0;
            z0=0;
            
            import matlab.net.*
            import matlab.net.http.*
            r = RequestMessage;
            h=0;
            t=0;
            while 1
                
                if abs(h)>=hnext
                    r = RequestMessage;
                    urll=strcat('http://localhost:8080/allvalue/',num2str(lat),'/',num2str(lon),'/',num2str(h),'/0/0');
                    uri = URI(urll);
                    resp = send(r,uri);
                    f=str2num(resp.Body.Data(1));
                    pamb=f(4);
                    tamb=f(3);
                    hnext=f(5);
                else
                    pamb=Pold;
                    tamb=Told;
                end
                
                Ramb=287 ;
                roamb=pamb/(Ramb*tamb);
                % roamb=1.22
                Rhel=2077.1;
                kt=kt+1;
                ro=roamb;
                rogasvir=pamb*1.15/(Rhel*tamb);
                rogasold=rogasvir;
                gama=1.06;
                Vol=(Pold/pamb)^(1/gama)*Vold;
                
                Voll=mvol*h+Vol0;
                %     Vol=Voll
                L = (Vol*3/4/pi)^(1/3);
                %                 figure(3)
                %                 hold on
                %                 plot(h,Vol,'o',h,Voll,'v')
                
                
                rogasvir=mgas/Vol;
                g=9.81;
                B=(ro-rogasvir)*g*Vol;
                A=pi*L^2;
                visco=1.81* 10^-5;
                Vrel=10;
                Re=roamb*Vrel*L/visco;
                
                
                cd= 4.808*(log(Re))^2/100 - 1.406*log(Re) + 10.490;
                % cd =0.75;
                if cd >0.85
                    cd =1.85;
                end
                
                if Vol>=Vburst
                    app.stateLabel.Text = 'burst balloon!!';
                    app.stateLamp.Color = [0 1 1];
                    app.undefindedLabel.Text=string(h);
                    break
                end
                FL=-Mgros*9.81+B;
                V2=(FL)/(0.5*ro*cd*A) ;
                V=V2^0.5;
                
                
                plot(app.UIAxes,t,h,'*')
                ax0=app.UIAxes;  %Assign your axes to variable
                hold( ax0, 'on' )


                if ~isreal(V)
                    h
                end
                pause(0.001)
                Pold=pamb;
                Vold=Vol;
                Told=tamb;
                kt=kt+1;
                h=h+500;
                t=500/V+t;
                
            end
            app.solveButton.Enable = 'on';
        end

        % Value changed function: balloontypeListBox
        function balloontypeListBoxValueChanged(app, event)
            value = app.balloontypeListBox.Value;
            app.UIFigure.Name =value;


        end

        % Menu selected function: startdataserverMenu
        function startdataserverMenuSelected(app, event)
            app.UIFigure.Name =string(rand);
            command = 'cmd.exe /c start python G:\apps\balloon\code\py\api\windapi.py';
            dos(command)
        end

        % Menu selected function: startdownloadserverMenu
        function startdownloadserverMenuSelected(app, event)
            command = 'cmd.exe /c start python G:\apps\balloon\code\py\api\dl.py';
            dos(command)
            
            
        end

        % Value changed function: launchlatitudeEditField
        function launchlatitudeEditFieldValueChanged(app, event)
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.serversMenu = uimenu(app.UIFigure);
            app.serversMenu.Text = 'servers';
            app.startdataserverMenu = uimenu(app.serversMenu);
            app.startdataserverMenu.MenuSelectedFcn = createCallbackFcn(app, @startdataserverMenuSelected, true);
            app.startdataserverMenu.Text = 'start data server ';
            app.startdownloadserverMenu = uimenu(app.serversMenu);
            app.startdownloadserverMenu.MenuSelectedFcn = createCallbackFcn(app, @startdownloadserverMenuSelected, true);
            app.startdownloadserverMenu.Text = 'start download server';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'time(s)')
            ylabel(app.UIAxes, 'Height(m)')
            app.solveButton = uibutton(app.UIFigure, 'push');
            app.solveButton.ButtonPushedFcn = createCallbackFcn(app, @solveButtonPushed, true);
            app.solveButton.Text = 'solve';
            app.balloontypeListBoxLabel = uilabel(app.UIFigure);
            app.balloontypeListBoxLabel.Text = 'balloon type';
            app.balloontypeListBox = uilistbox(app.UIFigure);
            app.balloontypeListBox.Items = {'TA 200', 'TA 300', 'TA 350', 'TA 450', 'TA 500 ', 'TA 600 ', 'TA 700 ', 'TA 800 ', 'TA 1000 ', 'TA 1200 ', 'TA 1500 ', 'TA 2000 ', 'TA 3000 ', 'TX 800 ', 'TX 1000 ', 'TX 1200 ', 'TX 2000 ', 'TX 3000'};
            app.balloontypeListBox.ValueChangedFcn = createCallbackFcn(app, @balloontypeListBoxValueChanged, true);
            app.balloontypeListBox.Value = 'TX 3000';
            app.stateLabel = uilabel(app.UIFigure);
            app.stateLabel.Text = 'state...';
            app.stateLamp = uilamp(app.UIFigure);
            app.burstheightLabel = uilabel(app.UIFigure);
            app.burstheightLabel.Text = 'burst height:';
            app.undefindedLabel = uilabel(app.UIFigure);
            app.undefindedLabel.Text = 'undefinded';
            app.launchlatitudeEditFieldLabel = uilabel(app.UIFigure);
            app.launchlatitudeEditFieldLabel.Text = 'launch latitude ';
            app.launchlatitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.launchlatitudeEditField.ValueChangedFcn = createCallbackFcn(app, @launchlatitudeEditFieldValueChanged, true);
            app.launchlongitudeEditFieldLabel = uilabel(app.UIFigure);
            app.launchlongitudeEditFieldLabel.Text = 'launch longitude';
            app.launchlongitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.latEditFieldLabel = uilabel(app.UIFigure);
            app.latEditFieldLabel.Text = 'lat';
            app.latEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end